classdef WoOController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SARActionSpacePanel             matlab.ui.container.Panel
        OtherActionsPanel               matlab.ui.container.Panel
        SpinLeftButton                  matlab.ui.control.Button
        AudioButton                     matlab.ui.control.Button
        SpinRightButton                 matlab.ui.control.Button
        BackwardDriveButton             matlab.ui.control.Button
        ForwardDriveButton              matlab.ui.control.Button
        ArmMovementsPanel               matlab.ui.container.Panel
        ComboLabel                      matlab.ui.control.Label
        Low5Label                       matlab.ui.control.Label
        Middle5Label                    matlab.ui.control.Label
        High5Label                      matlab.ui.control.Label
        HighLeftLowRightButton          matlab.ui.control.Button
        HighRightLowLeftButton          matlab.ui.control.Button
        Low5LeftButton                  matlab.ui.control.Button
        Low5RightButton                 matlab.ui.control.Button
        Low5sButton                     matlab.ui.control.Button
        Middle5LeftButton               matlab.ui.control.Button
        Middle5RightButton              matlab.ui.control.Button
        Middle5Button                   matlab.ui.control.Button
        High5LeftButton                 matlab.ui.control.Button
        High5RightButton                matlab.ui.control.Button
        High5sButton                    matlab.ui.control.Button
        WaveLeftButton                  matlab.ui.control.Button
        WaveRightButton                 matlab.ui.control.Button
        NeutralButton                   matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ParticipantIdentifierEditField  matlab.ui.control.EditField
        ParticipantIdentifierEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Establish arduino connection 
        clear; clc; % must clear out old connections 
        s = serialport('COM4',9600); % create serial object 
        
        % Create variables 
        action = 0; 
        timestamp = datetime;
        row = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % start arduino process & set robot to neutral position
            app.action = 0;
            write(app.s,app.action,'uint8');

            % Create excel sheet
            TableTitle = {'date-time','action'};
            range = "A"+app.row+":"+"B"+app.row;
            writecell(TableTitle,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: NeutralButton
        function NeutralButtonPushed(app, event)
            app.action = 0;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: WaveRightButton
        function WaveRightButtonPushed(app, event)
            app.action = 1;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: WaveLeftButton
        function WaveLeftButtonPushed(app, event)
            app.action = 2;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: High5sButton
        function High5sButtonPushed(app, event)
            app.action = 3;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: High5RightButton
        function High5RightButtonPushed(app, event)
            app.action = 4;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: High5LeftButton
        function High5LeftButtonPushed(app, event)
            app.action = 5;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: Middle5Button
        function Middle5ButtonPushed(app, event)
            app.action = 6;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: Middle5RightButton
        function Middle5RightButtonPushed(app, event)
            app.action = 7;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: Middle5LeftButton
        function Middle5LeftButtonPushed(app, event)
            app.action = 8;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: Low5sButton
        function Low5sButtonPushed(app, event)
            app.action = 9;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: Low5RightButton
        function Low5RightButtonPushed(app, event)
            app.action = 10;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: Low5LeftButton
        function Low5LeftButtonPushed(app, event)
            app.action = 11;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: HighRightLowLeftButton
        function HighRightLowLeftButtonPushed(app, event)
            app.action = 12;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: HighLeftLowRightButton
        function HighLeftLowRightButtonPushed(app, event)
            app.action = 13;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: ForwardDriveButton
        function ForwardDriveButtonPushed(app, event)
            app.action = 14;
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: BackwardDriveButton
        function BackwardDriveButtonPushed(app, event)
            app.action = 15; 
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: SpinRightButton
        function SpinRightButtonPushed(app, event)
            app.action = 16; 
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end

        % Button pushed function: AudioButton
        function AudioButtonPushed(app, event)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           % Set robot to neutral position  
           app.action = 0;
           write(app.s,app.action,'uint8');
           

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);

            % Disconnect from Arduino
            delete(app.s)
        end

        % Button pushed function: SpinLeftButton
        function SpinLeftButtonPushed(app, event)
            app.action = 17; 
            write(app.s,app.action,'uint8');

            % Send datapoint to excel file
            app.timestamp = datetime;
            app.row = app.row+1;
            range = "A"+app.row+":"+"B"+app.row;
            datainput = {app.timestamp,app.action};
            writecell(datainput,'C:\Users\18049\OneDrive - The University of Colorado Denver\Documents\MATLAB\WoOController.xlsx','Sheet',app.ParticipantIdentifierEditField.Value,'Range',range);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParticipantIdentifierEditFieldLabel = uilabel(app.UIFigure);
            app.ParticipantIdentifierEditFieldLabel.Text = 'Participant Identifier';
            app.ParticipantIdentifierEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SARActionSpacePanel = uipanel(app.UIFigure);
            app.SARActionSpacePanel.Title = 'SAR Action Space';
            app.ArmMovementsPanel = uipanel(app.SARActionSpacePanel);
            app.ArmMovementsPanel.Title = 'Arm Movements';
            app.NeutralButton = uibutton(app.ArmMovementsPanel, 'push');
            app.NeutralButton.ButtonPushedFcn = createCallbackFcn(app, @NeutralButtonPushed, true);
            app.NeutralButton.Text = 'Neutral';
            app.WaveRightButton = uibutton(app.ArmMovementsPanel, 'push');
            app.WaveRightButton.ButtonPushedFcn = createCallbackFcn(app, @WaveRightButtonPushed, true);
            app.WaveRightButton.Text = 'Wave Right';
            app.WaveLeftButton = uibutton(app.ArmMovementsPanel, 'push');
            app.WaveLeftButton.ButtonPushedFcn = createCallbackFcn(app, @WaveLeftButtonPushed, true);
            app.WaveLeftButton.Text = 'Wave Left';
            app.High5sButton = uibutton(app.ArmMovementsPanel, 'push');
            app.High5sButton.ButtonPushedFcn = createCallbackFcn(app, @High5sButtonPushed, true);
            app.High5sButton.Text = 'High-5s';
            app.High5RightButton = uibutton(app.ArmMovementsPanel, 'push');
            app.High5RightButton.ButtonPushedFcn = createCallbackFcn(app, @High5RightButtonPushed, true);
            app.High5RightButton.Text = 'High-5 Right';
            app.High5LeftButton = uibutton(app.ArmMovementsPanel, 'push');
            app.High5LeftButton.ButtonPushedFcn = createCallbackFcn(app, @High5LeftButtonPushed, true);
            app.High5LeftButton.Text = 'High-5 Left';
            app.Middle5Button = uibutton(app.ArmMovementsPanel, 'push');
            app.Middle5Button.ButtonPushedFcn = createCallbackFcn(app, @Middle5ButtonPushed, true);
            app.Middle5Button.Text = 'Middle-5';
            app.Middle5RightButton = uibutton(app.ArmMovementsPanel, 'push');
            app.Middle5RightButton.ButtonPushedFcn = createCallbackFcn(app, @Middle5RightButtonPushed, true);
            app.Middle5RightButton.Text = 'Middle-5 Right';
            app.Middle5LeftButton = uibutton(app.ArmMovementsPanel, 'push');
            app.Middle5LeftButton.ButtonPushedFcn = createCallbackFcn(app, @Middle5LeftButtonPushed, true);
            app.Middle5LeftButton.Text = 'Middle-5 Left';
            app.Low5sButton = uibutton(app.ArmMovementsPanel, 'push');
            app.Low5sButton.ButtonPushedFcn = createCallbackFcn(app, @Low5sButtonPushed, true);
            app.Low5sButton.Text = 'Low-5s';
            app.Low5RightButton = uibutton(app.ArmMovementsPanel, 'push');
            app.Low5RightButton.ButtonPushedFcn = createCallbackFcn(app, @Low5RightButtonPushed, true);
            app.Low5RightButton.Text = 'Low-5 Right';
            app.Low5LeftButton = uibutton(app.ArmMovementsPanel, 'push');
            app.Low5LeftButton.ButtonPushedFcn = createCallbackFcn(app, @Low5LeftButtonPushed, true);
            app.Low5LeftButton.Text = 'Low-5 Left';
            app.HighRightLowLeftButton = uibutton(app.ArmMovementsPanel, 'push');
            app.HighRightLowLeftButton.ButtonPushedFcn = createCallbackFcn(app, @HighRightLowLeftButtonPushed, true);
            app.HighRightLowLeftButton.Text = 'High Right - Low Left';
            app.HighLeftLowRightButton = uibutton(app.ArmMovementsPanel, 'push');
            app.HighLeftLowRightButton.ButtonPushedFcn = createCallbackFcn(app, @HighLeftLowRightButtonPushed, true);
            app.HighLeftLowRightButton.Text = 'High Left- Low Right';
            app.High5Label = uilabel(app.ArmMovementsPanel);
            app.High5Label.Text = 'High-5';
            app.Middle5Label = uilabel(app.ArmMovementsPanel);
            app.Middle5Label.Text = 'Middle-5';
            app.Low5Label = uilabel(app.ArmMovementsPanel);
            app.Low5Label.Text = 'Low-5';
            app.ComboLabel = uilabel(app.ArmMovementsPanel);
            app.ComboLabel.Text = 'Combo';
            app.OtherActionsPanel = uipanel(app.SARActionSpacePanel);
            app.OtherActionsPanel.Title = 'Other Actions';
            app.ForwardDriveButton = uibutton(app.OtherActionsPanel, 'push');
            app.ForwardDriveButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardDriveButtonPushed, true);
            app.ForwardDriveButton.Text = 'Forward Drive';
            app.BackwardDriveButton = uibutton(app.OtherActionsPanel, 'push');
            app.BackwardDriveButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardDriveButtonPushed, true);
            app.BackwardDriveButton.Text = 'Backward Drive';
            app.SpinRightButton = uibutton(app.OtherActionsPanel, 'push');
            app.SpinRightButton.ButtonPushedFcn = createCallbackFcn(app, @SpinRightButtonPushed, true);
            app.SpinRightButton.Text = 'Spin Right';
            app.AudioButton = uibutton(app.OtherActionsPanel, 'push');
            app.AudioButton.ButtonPushedFcn = createCallbackFcn(app, @AudioButtonPushed, true);
            app.AudioButton.Text = 'Audio';
            app.SpinLeftButton = uibutton(app.OtherActionsPanel, 'push');
            app.SpinLeftButton.ButtonPushedFcn = createCallbackFcn(app, @SpinLeftButtonPushed, true);
            app.SpinLeftButton.Text = 'Spin Left';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WoOController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end